classdef AppPolinomios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PolinomioLabel  matlab.ui.control.Label
        Polinomio       matlab.ui.control.EditField
        Buscar          matlab.ui.control.Button
        RaizLabel       matlab.ui.control.Label
        Raiz            matlab.ui.control.EditField
        Grafica         matlab.ui.control.UIAxes
        Graficas        matlab.ui.container.ButtonGroup
        Grafica1        matlab.ui.control.RadioButton
        Grafica2        matlab.ui.control.RadioButton
        Grafica3        matlab.ui.control.RadioButton
        Grafica4        matlab.ui.control.RadioButton
        Graficar        matlab.ui.control.Button
        Limpiar         matlab.ui.control.Button
        MetododeRaicesdePolinomiosLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Buscar
        function BuscarButtonPushed(app, event)
            A = textscan(app.Polinomio.Value,'%f','Delimiter',',');
            A = A{1};
            r = RaicesDP(A);
            app.Raiz.Value=num2str(r);
        end

        % Button pushed function: Graficar
        function GraficarButtonPushed(app, event)
            x = -10:0.1:10;
            if(app.Grafica1.Value)
                p1 = @(x) x.^3 -6*x.^2 +11*x -6;
                y = p1(x);
                plot(app.Grafica,x,y,'.r')
            elseif(app.Grafica2.Value)
                p1 = @(x) x.^3 -x.^2 -4*x +4;
                y = p1(x);
                plot(app.Grafica,x,y,'.r')
            elseif(app.Grafica3.Value)
                p1 = @(x) -x.^4 +10*x.^2 -9;
                y = p1(x);
                plot(app.Grafica,x,y,'.r')
            elseif(app.Grafica4.Value)
                p1 = @(x) 2*x.^3 +x.^2 -13*x +6;
                y = p1(x);
                plot(app.Grafica,x,y,'.r')
            end
        end

        % Button pushed function: Limpiar
        function LimpiarButtonPushed(app, event)
            app.Raiz.Value = '';
            app.Polinomio.Value = '';
            plot(app.Grafica,1,1,'-r')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PolinomioLabel = uilabel(app.UIFigure);
            app.PolinomioLabel.Text = 'Polinomio:';
            app.Polinomio = uieditfield(app.UIFigure, 'text');
            app.Buscar = uibutton(app.UIFigure, 'push');
            app.Buscar.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.Buscar.Text = 'Buscar';
            app.RaizLabel = uilabel(app.UIFigure);
            app.RaizLabel.Text = 'Raiz:';
            app.Raiz = uieditfield(app.UIFigure, 'text');
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Polinomios')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            app.Graficas = uibuttongroup(app.UIFigure);
            app.Graficas.Title = 'Graficas';
            app.Grafica1 = uiradiobutton(app.Graficas);
            app.Grafica1.Text = 'Grafica 1';
            app.Grafica1.Value = true;
            app.Grafica2 = uiradiobutton(app.Graficas);
            app.Grafica2.Text = 'Grafica 2';
            app.Grafica3 = uiradiobutton(app.Graficas);
            app.Grafica3.Text = 'Grafica 3';
            app.Grafica4 = uiradiobutton(app.Graficas);
            app.Grafica4.Text = 'Grafica 4';
            app.Graficar = uibutton(app.UIFigure, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.Graficar.Text = 'Graficar';
            app.Limpiar = uibutton(app.UIFigure, 'push');
            app.Limpiar.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.Limpiar.Text = 'Limpiar';
            app.MetododeRaicesdePolinomiosLabel = uilabel(app.UIFigure);
            app.MetododeRaicesdePolinomiosLabel.Text = 'Metodo de Raices de Polinomios';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppPolinomios

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end